classdef winSCH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        MODORELATRIOLabel               matlab.ui.control.Label
        DocumentGrid                    matlab.ui.container.GridLayout
        search_List                     matlab.ui.control.HTML
        tool_nRows                      matlab.ui.control.Label
        search_Suggestions              matlab.ui.control.ListBox
        search_Table                    matlab.ui.control.Table
        search_Metadata                 matlab.ui.control.Label
        search_entryPointPanel          matlab.ui.container.Panel
        search_entryPointGrid           matlab.ui.container.GridLayout
        search_entryPoint               matlab.ui.control.EditField
        search_entryPointImage          matlab.ui.control.Image
        menu_TabGroupGrid               matlab.ui.container.GridLayout
        menu_ControlGrid                matlab.ui.container.GridLayout
        menu_Button4Grid                matlab.ui.container.GridLayout
        menu_Button4Icon                matlab.ui.control.Image
        menu_Button4Label               matlab.ui.control.Label
        menu_Button3Grid                matlab.ui.container.GridLayout
        menu_Button3Icon                matlab.ui.control.Image
        menu_Button3Label               matlab.ui.control.Label
        menu_Button2Grid                matlab.ui.container.GridLayout
        menu_Button2Icon                matlab.ui.control.Image
        menu_Button2Label               matlab.ui.control.Label
        menu_Button1Grid                matlab.ui.container.GridLayout
        menu_Button1Icon                matlab.ui.control.Image
        menu_Button1Label               matlab.ui.control.Label
        menu_Underline                  matlab.ui.control.Image
        menu_TabGroup                   matlab.ui.container.TabGroup
        Tab1                            matlab.ui.container.Tab
        Tab1Grid                        matlab.ui.container.GridLayout
        filter_SecundaryPanel           matlab.ui.container.Panel
        filter_SecundaryGrid            matlab.ui.container.GridLayout
        filter_Status                   matlab.ui.control.DropDown
        filter_StatusCheckBox           matlab.ui.control.CheckBox
        filter_TradeName                matlab.ui.control.EditField
        filter_TradeNameCheckBox        matlab.ui.control.CheckBox
        filter_Model                    matlab.ui.control.EditField
        filter_ModelCheckBox            matlab.ui.control.CheckBox
        filter_Manufacturer             matlab.ui.control.EditField
        filter_ManufacturerCheckBox     matlab.ui.control.CheckBox
        filter_CNPJ                     matlab.ui.control.EditField
        filter_CNPJCheckBox             matlab.ui.control.CheckBox
        filter_Applicant                matlab.ui.control.EditField
        filter_ApplicantCheckBox        matlab.ui.control.CheckBox
        filter_ProductList              matlab.ui.control.DropDown
        filter_ProductListCheckBox      matlab.ui.control.CheckBox
        filter_Certification            matlab.ui.control.EditField
        filter_CertificationCheckBox    matlab.ui.control.CheckBox
        filter_SecundaryPanelLabel      matlab.ui.control.Label
        filter_PrimaryPanel             matlab.ui.container.ButtonGroup
        filter_PrimaryOption2_4         matlab.ui.control.RadioButton
        filter_PrimaryOption2_3         matlab.ui.control.RadioButton
        filter_PrimaryOption2_2         matlab.ui.control.RadioButton
        filter_PrimaryOption3           matlab.ui.control.CheckBox
        filter_PrimaryOption2           matlab.ui.control.RadioButton
        filter_PrimaryOption1           matlab.ui.control.RadioButton
        filter_PrimaryPanelLabel        matlab.ui.control.Label
        Tab2                            matlab.ui.container.Tab
        Tab2Grid                        matlab.ui.container.GridLayout
        notes_Tree                      matlab.ui.container.Tree
        notes_addImage                  matlab.ui.control.Image
        notes_Panel2                    matlab.ui.container.Panel
        notes_Panel2Grid                matlab.ui.container.GridLayout
        notes_Value                     matlab.ui.control.TextArea
        notes_ValueLabel                matlab.ui.control.Label
        notes_Type                      matlab.ui.control.DropDown
        notes_TypeLabel                 matlab.ui.control.Label
        notes_Label2                    matlab.ui.control.Label
        notes_Panel1                    matlab.ui.container.Panel
        notes_Panel1Grid                matlab.ui.container.GridLayout
        notes_ProductInfo               matlab.ui.control.HTML
        notes_Label1                    matlab.ui.control.Label
        Tab3                            matlab.ui.container.Tab
        Tab3Grid                        matlab.ui.container.GridLayout
        notes_addImage_2                matlab.ui.control.Image
        report_SelectedProductPanel     matlab.ui.container.Panel
        report_SelectedProductGrid      matlab.ui.container.GridLayout
        TipoDropDown                    matlab.ui.control.DropDown
        TipoDropDownLabel               matlab.ui.control.Label
        report_ComplementaryNotes       matlab.ui.control.TextArea
        report_ComplementaryNotesLabel  matlab.ui.control.Label
        report_MonetaryValue            matlab.ui.control.NumericEditField
        report_MonetaryValueLabel       matlab.ui.control.Label
        report_Quantity                 matlab.ui.control.NumericEditField
        report_QuantityLabel            matlab.ui.control.Label
        report_SelectedProductLabel     matlab.ui.control.Label
        report_Tree                     matlab.ui.container.Tree
        Node                            matlab.ui.container.TreeNode
        APPLEIPHONE22Node               matlab.ui.container.TreeNode
        Node_3                          matlab.ui.container.TreeNode
        report_TreeLabel                matlab.ui.control.Label
        Tab4                            matlab.ui.container.Tab
        misc_ElevationSourceGrid        matlab.ui.container.GridLayout
        basemapPanel                    matlab.ui.container.Panel
        basemapGrid                     matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        QtdmnimacaracteresSpinner       matlab.ui.control.Spinner
        QtdmnimacaracteresSpinnerLabel  matlab.ui.control.Label
        selectedBasemap                 matlab.ui.control.DropDown
        selectedBasemapLabel            matlab.ui.control.Label
        AlgoritmodesugestodepalavraschaveLabel  matlab.ui.control.Label
        GridLayout9                     matlab.ui.container.GridLayout
        Button                          matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        PastadetrabalhoLabel            matlab.ui.control.Label
        htmlListView                    matlab.ui.control.CheckBox
        ASPECTOSGERAISLabel             matlab.ui.control.Label
        menu_Grid                       matlab.ui.container.GridLayout
        jsBackDoor                      matlab.ui.control.HTML
        menu_btnInfo                    matlab.ui.control.Image
        menu_btnReport                  matlab.ui.control.Image
        menu_btnSearch                  matlab.ui.control.Image
        menu_Title                      matlab.ui.control.Label
        menu_btnSettings                matlab.ui.control.Image
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        CallingApp
        General

        rootFolder
        userPath

        timerObj

        rawDataTable
        releasedData
        cacheData

        annotationTable
        annotationTempTable
        
        changingSearch   = false
        previousSearch   = ''
        previousItemData = 0
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function TimerCreation(app)
            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.TimerFcn);
            start(app.timerObj)
        end


        %-----------------------------------------------------------------%
        function TimerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                
                jsBackDoor_Customizations(app), drawnow
                ReadFiles(app)
                fcn.parpoolCheck()
            end
        end
        

        %-----------------------------------------------------------------%
        function ReadFiles(app)
            % app.General
            app.General = jsondecode(fileread(fullfile(app.rootFolder, 'Settings', 'GeneralSettings.json')));

            % app.rawDataTable
            % app.releaseData
            % app.cacheData
            computerName = getenv('COMPUTERNAME');
            switch computerName
                case app.General.webApp.computerName
                    appMode    = 'webApp';
                    fileFolder = getenv('USERPROFILE');
                    filePath   = app.General.webApp.filePath;
                    fileName   = app.General.webApp.fileName;

                otherwise
                    appMode    = 'desktopApp';
                    fileFolder = app.rootFolder;
                    filePath   = app.General.desktopApp.filePath;
                    fileName   = app.General.desktopApp.fileName;
            end
            fileFullPath = fullfile(fileFolder, filePath, fileName);
            
            [app.rawDataTable, ...
             app.releasedData, ...
             app.cacheData,    ...
             matFullFile] = fcn.ReadSCHTable(fileFullPath, app.General.cacheColumns);

            if ~isempty(matFullFile)
                [~, fileMATName] = fileparts(fileName);
                fileMATName      = [fileMATName '.mat'];

                eval(sprintf('app.General.%s.fileName = ''%s'';', appMode, fileMATName))
                fcn.updateFile_GeneralSettings(app.General, app.rootFolder)
            end

            % app.annotationTable
            % app.annotationTempTable
            annotationFileFullPath      = fullfile(fileFolder, filePath, app.General.annotation.fileName);
            annotationTempFileFullPath  = fullfile(fileFolder, filePath, app.General.annotation.tempFileName);

            msgError = {};
            try
                app.annotationTable     = class.Annotation.read('Local', annotationFileFullPath);
            catch ME
                msgError{end+1}         = ['annotationTable: ' ME.message];
                app.annotationTable     = class.Annotation.AnnotationTable();                
            end

            try
                app.annotationTempTable = class.Annotation.read('Local', annotationTempFileFullPath);
            catch ME
                msgError{end+1}         = ['annotationTempTable: ' ME.message];
                app.annotationTempTable = class.Annotation.AnnotationTable();                
            end

            % if ~isempty(msgError)
            %     uialert(app.UIFigure, strjoin(msgError, '\n'), '', 'Icon', 'error')
            % end
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource           = sprintf('<!DOCTYPE html>\n<html>\n<head>\n<script>\n%s\n</script>\n</head>\n\n<body>\n</body>\n</html>', fileread(fullfile(ccTools.Path, 'css&js', 'compCustomization.js')));
            app.jsBackDoor.HTMLEventReceivedFcn = @(~, evt)jsBackDoor_keyDownListener(app, evt);
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            % Cria keydown listeners, em JS, para os componentes descritos
            % em objList.
            objList = {app.search_entryPoint, app.search_Suggestions};
            for ii = 1:numel(objList)
                objList{ii}.UserData = struct(objList{ii}).Controller.ViewModel.Id;
            end            
            sendEventToHTMLSource(app.jsBackDoor, "addKeyDownListener", struct('DataName', 'app.search_entryPoint',  'DataTag', app.search_entryPoint.UserData));
            sendEventToHTMLSource(app.jsBackDoor, "addKeyDownListener", struct('DataName', 'app.search_Suggestions', 'DataTag', app.search_Suggestions.UserData));

            % Força a exclusão da tela de progresso da inicialização de um 
            % webapp, caso aplicável.
            sendEventToHTMLSource(app.jsBackDoor, "delProgressDialog");

            % Aplica customizações estéticas ao(s) componente(s).
            ccTools.compCustomizationV2(app.jsBackDoor, app.search_entryPoint, 'borderWidth', '0px');
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_keyDownListener(app, event)

            % Foi adicionado o evento JS-keydown das teclas ["ArrowUp", "ArrowDown", "Enter", "Escape"]
            % aos componentes app.search_entryPoint (matlab.ui.control.EditField) e app.search_Suggestions
            % (matlab.ui.control.ListBox) usando o JS-backdoor app.jsBackDoor (matlab.ui.control.HTML).
    
            % Em relação aos callbacks configuráveis no próprio MATLAB:
            % - matlab.ui.control.EditField 
            %   (a) Possui os eventos "ValueChangedFcn" e "ValueChangingFcn".
            %   (b) Não responde à tecla "Escape".
            %   (c) Responde às teclas "ArrowUp" e "ArrowDown", controlando a posição 
            %       do cursor (início e fim, respectivamente)
            %   (d) Reponde à tecla "Enter", executando "ValueChangingFcn" e "ValueChangedFcn", 
            %       nesse ordem.
    
            % - matlab.ui.control.ListBox
            %   (a) possui os eventos "ValueChangedFcn", "ClickedFcn" e "DoubleClickedFcn".
            %   (b) Responde às teclas "ArrowUp" e "ArrowDown", executando "ValueChangedFcn", 
            %       desde que não estejam selecionadas as suas "bordas" (valor 1 e "ArrowUp", 
            %       ou valor n e "ArrowDown", por exemplo).
    
            % Num eventual clique de uma das teclas ["ArrowUp", "ArrowDown", "Enter", "Escape"],
            % o trigger do evento JS-keydown ocorre antes do trigger dos eventos padrões dos 
            % componentes matlab.ui.control.EditField e matlab.ui.control.ListBox.
    
            % Isso é bom, mas ruim por criar uma complexidade extra! :(
    
            % Quando altero o conteúdo de app.search_entryPoint, sem alterar o seu foco, será executado 
            % o evento "ValueChangingFcn". Se pressiono a tecla "Enter", será executada essa função 
            % (jsBackDoor_keyDownListener) antes de atualizar a propriedade "Value" do app.search_entryPoint.
    
            % Por conta disso, é essencial inserir waitfor(app.search_entryPoint, 'Value')

            switch event.HTMLEventData
                case 'app.search_entryPoint'
                    focus(app.jsBackDoor)

                    switch event.HTMLEventName
                        case 'Escape'
                            set(app.search_Suggestions, Visible=0, Value={})

                            checkIfEntryPointNeedUpdate(app)
                            if numel(app.search_entryPoint.Value) <= 1
                                set(app.search_entryPoint, 'FontColor', [.8,.8,.8], 'Value', 'O que você quer pesquisar?')
                            end

                        otherwise
                            % Ao trocar o foco de app.search_entryPoint para app.jsBackDoor, o MATLAB executa 
                            % logicamente a operação: app.search_entryPoint.Value = app.search_entryPoint.ChangingValue
                            % Ao inserir waitfor, teremos a garantia de usar o valor atual do objeto...
                            checkIfEntryPointNeedUpdate(app)
                            if numel(app.search_entryPoint.Value) <= 1
                                sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));

                            else
                                switch event.HTMLEventName
                                    case 'ArrowDown'
                                        app.previousItemData = 1;
            
                                        set(app.search_Suggestions, 'Visible', 1, 'Value', 1)
                                        scroll(app.search_Suggestions, "top")
                                        focus(app.search_Suggestions)
                    
                                    case 'ArrowUp'
                                        nMaxValues = numel(app.search_Suggestions.Items);

                                        app.previousItemData = nMaxValues;            
                                        set(app.search_Suggestions, 'Visible', 1, 'Value', nMaxValues)
                                        scroll(app.search_Suggestions, "bottom")
                                        focus(app.search_Suggestions)
                    
                                    case 'Enter'                                        
                                        set(app.search_Suggestions, Visible=0, Value={})
                                        search_entryPointImageClicked(app)
                                        sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));
                                end
                            end
                    end

                %---------------------------------------------------------%
                case 'app.search_Suggestions'
                    switch event.HTMLEventName
                        case 'ArrowDown'
                            nMaxValues = numel(app.search_Suggestions.Items);

                            if (app.previousItemData == nMaxValues) && (app.search_Suggestions.Value == nMaxValues)
                                app.previousItemData = 0;
                                
                                set(app.search_Suggestions, Visible=0, Value={})
                                sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));
                            else
                                app.previousItemData = app.search_Suggestions.Value;
                            end
        
                        case 'ArrowUp'
                            if (app.previousItemData == 1) && (app.search_Suggestions.Value == 1)
                                app.previousItemData = 0;

                                set(app.search_Suggestions, Visible=0, Value={})
                                sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));
                            else
                                app.previousItemData = app.search_Suggestions.Value;
                            end
        
                        case 'Enter'
                            eventValue = app.search_Suggestions.Items{app.search_Suggestions.Value};
                            panelVisibility = false;

                            app.search_entryPoint.Value = eventValue;
                            filterEntryChanging(app, eventValue, panelVisibility)
                            
                            sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));

                        case 'Escape'
                            set(app.search_Suggestions, Visible=0, Value={})
                    end
            end
            drawnow
        end


        %-----------------------------------------------------------------%
        function checkIfEntryPointNeedUpdate(app)
            if ~strcmp(app.search_entryPoint.Value, app.previousSearch)
                waitfor(app.search_entryPoint, 'Value')
            end
        end


        %-----------------------------------------------------------------% LEANDRO        
        function FilterType = filter_Columns_Type(app)
             FilterType = [];

                if app.filter_CheckBox_ProductName.Value
                    FilterType = [FilterType; string(app.filter_CheckBox_ProductName.Text)];
                end
                if app.filter_CheckBox_ProductModel.Value
                    FilterType = [FilterType; string(app.filter_CheckBox_ProductModel.Text)];
                end
                if app.filter_CheckBox_Manufacturer.Value
                    FilterType = [FilterType; string(app.filter_CheckBox_Manufacturer.Text)];
                end
                if app.filter_CheckBox_Applicant.Value
                    FilterType = [FilterType; string(app.filter_CheckBox_Applicant.Text)];
                end
                if app.filter_PrimaryOption3.Value
                    FilterType = [FilterType; string(app.filter_PrimaryOption3.Text)];
                end
            FilterType = FilterType;
        end


        %-----------------------------------------------------------------%
        function rawColumnData = FindCache_StringListOfSelectedColumn(app)
        % function rawColumnData = FindCache_StringListOfSelectedColumn(app, FilterType)
        % 
        %     rawColumnData = [];
        %     for ii = 1:numel(FilterType)
        %         selectedColumn = string(FilterType(ii));
        %         cacheIndex     = find(strcmp(selectedColumn, {app.cacheSCH.Column}), 1);
        %         rawColumnData  = [rawColumnData; app.cacheSCH(cacheIndex).uniqueValues];
        %     end


            % selectedColumn = app.filter_TypePanel_2.SelectedObject.Text;
            selectedColumn = 'Nome Comercial';
            cacheIndex     = find(strcmp(selectedColumn, {app.cacheData.Column}), 1);
            rawColumnData  = app.cacheData(cacheIndex).uniqueValues;
        end


        %-----------------------------------------------------------------%
        function rawColumnDataLev = FindCache_TokenListOfSelectedColumn(app)
        % function rawColumnDataLev = FindCache_TokenListOfSelectedColumn(app, FilterType)
        % 
        %     rawColumnDataLev = [];
        %     for ii = 1:numel(FilterType)
        %         selectedColumn = FilterType(ii);
        %         cacheIndex     = find(strcmp(selectedColumn, {app.cacheSCH.Column}), 1);
        %         rawColumnDataLev = [rawColumnDataLev; app.cacheSCH(cacheIndex).uniqueTokens];
        %     end


            % selectedColumn = app.filter_TypePanel_2.SelectedObject.Text;
            selectedColumn = 'Nome Comercial';
            cacheIndex     = find(strcmp(selectedColumn, {app.cacheData.Column}), 1);
            rawColumnDataLev = app.cacheData(cacheIndex).uniqueTokens;
        end


        %-----------------------------------------------------------------%
        function layout_EntryPointColor(app, redFlag)
            if redFlag
                fontColor = [1,0,0];
            else
                fontColor = [0,0,0];
            end
            app.search_entryPoint.FontColor = fontColor;
            drawnow
        end


        %-----------------------------------------------------------------%
        function layout_checkTableVisibility(app)
            if ~app.search_Table.Visible
                ccTools.compCustomizationV2(app.jsBackDoor, app.search_Table, 'fontSize', '10px', 'paddingTop', '0px');
                app.search_Table.Visible    = 1;
                app.search_Metadata.Visible = 1;
                app.tool_nRows.Visible = 1;
            end
        end


        %-----------------------------------------------------------------%
        function filterEntryChanging(app, eventValue, panelVisibility)
            value2Search = fcn.PreProcessedData(eventValue);

            if numel(value2Search) <= 1
                set(app.search_Suggestions, Visible=0, Items={}, ItemsData=[])
                set(app.search_entryPointImage, Enable=0)
            else
                set(app.search_entryPointImage, Enable=1)
                if ~strcmp(eventValue, app.previousSearch)
                    filterSuggestion(app, value2Search, panelVisibility)                    
                end
            end

            app.previousSearch = eventValue;
            app.changingSearch = true;
        end


        %-----------------------------------------------------------------%
        function filterSuggestion(app, value2Search, panelVisibility)
            idxFiltered     = [];

            % % LEANDRO
            % FilterType = filter_Columns_Type(app);
            % cacheStringList = FindCache_StringListOfSelectedColumn(app, FilterType);

            cacheStringList = FindCache_StringListOfSelectedColumn(app);
            cacheTokenList  = [];

            nMaxValues      = class.Constants.nMaxSuggested;
            
            algorithmsOrder = {'startsWith', 'Contains', 'Levenshtein'};
            redFontFlag     = false;

            for kk = algorithmsOrder
                methodName = char(kk);
                
                if strcmp(methodName, 'Levenshtein')
                    if numel(idxFiltered) >= nMaxValues
                        break
                    end

                    if isempty(idxFiltered)
                        redFontFlag = true;
                    end

                    cacheTokenList = FindCache_TokenListOfSelectedColumn(app);
                    % cacheTokenList = FindCache_TokenListOfSelectedColumn(app, FilterType);
                end

                idxFiltered = fcn.getSimilarStrings(methodName, cacheStringList, cacheTokenList, value2Search, idxFiltered, nMaxValues);
            end

            set(app.search_Suggestions, 'Visible', panelVisibility, 'Value', {}, 'Items', cacheStringList(idxFiltered), 'ItemsData', 1:numel(idxFiltered))
            layout_EntryPointColor(app, redFontFlag)
        end


        %-----------------------------------------------------------------%
        function filter_main(app, words2Search)
            filteredIndex = [];

            % Nesse estágio do desenvolvimento, está fixada a pesquisa
            % apenas na coluna 'Nome Comercial'. Isso tá definido lá nas
            % funções que gera o cache que será usado pelo algoritmo de
            % sugestão, nas funções FindCache_StringListOfSelectedColumn e 
            % FindCache_TokenListOfSelectedColumn.

            for ii = 1:numel(words2Search)
                filteredIndex = [filteredIndex; find(strcmp(app.rawDataTable.('_Nome Comercial'), words2Search{ii}))];
            end
            app.search_Table.UserData = filteredIndex;
            
            filter_complementar(app);
            htmlListViewValueChanged(app)


            % % LEANDRO
            % filteredIndex = [];
            % 
            % for ii = 1:numel(FilterType)
            %     for jj = 1:numel(words2Search)
            %         filteredIndex = [filteredIndex; find(strcmp(app.rawSCHTable.(sprintf('_%s', FilterType(ii))), words2Search{jj}))];
            %     end
            % end
            % filteredIndex = unique(filteredIndex, 'stable');
            % 
            % app.search_Table.UserData = struct('PrimaryIndex', filteredIndex, 'ComplementaryIndex', []);
            % app.search_Table.Data =   app.rawSCHTable(app.search_Table.UserData.PrimaryIndex,1:11);
            % 
            % layout_checkTableVisibility(app)
            % drawnow
            % 
            % % ROTINA PARA "LIMPAR OS FILRTOS COMPLEMENTARES"
            % set(findobj(app.filter_SecundaryGrid, 'Type', 'uicheckbox'),  'Value', 0)
            % set(findobj(app.filter_SecundaryGrid, 'Type', 'uieditfield'), 'Value', '', 'Enable', 0)
            % set(findobj(app.filter_SecundaryGrid, 'Type', 'uidropdown'),  'Items', {}, 'Enable', 0)
        end


        %-----------------------------------------------------------------%
        function filteredIndex = filter_complementar(app)
            filteredIndex = app.search_Table.UserData;

            columnIndex = [2 16 3 12 13 14 11];
            app.search_Table.Data = app.rawDataTable(filteredIndex, columnIndex);
            app.tool_nRows.Text   = sprintf('# %d', numel(filteredIndex));
        end


        %-----------------------------------------------------------------%
        function HTMLEventReceivedFcn(app, event)
            switch event.Source
                case app.search_List
                    app.notes_ProductInfo.HTMLSource = event.HTMLEventData;
            end
        end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            try
                % PATH SEARCH
                app.rootFolder = fileparts(mfilename('fullpath'));

                if isdeployed
                    [~, result]          = system('path');
                    executableFileFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
                    if isfile(fullfile(executableFileFolder, sprintf('%s.exe', class.Constants.appName)))
                        app.rootFolder = executableFileFolder;
                    end
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % MOVER ESSES WARNINGS PARA UMA FUNÇÃO DE CCTOOLS %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % WARNING MESSAGES
                warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                warning('off', 'MATLAB:structOnObject')
                warning('off', 'MATLAB:class:DestructorError')
                warning('off', 'MATLAB:modes:mode:InvalidPropertySet')
                warning('off', 'MATLAB:table:RowsAddedExistingVars')
                warning('off', 'MATLAB:colon:operandsNotRealScalar')

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % TRECHO TEMPORÁRIO RELACIONADO À GUI. REMOVER NA COMPILAÇÃO! %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                app.GridLayout2.ColumnWidth(1:2)  = {0, 0};

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % TRECHO TEMPORÁRIO RELACIONADO À GUI. REMOVER NA COMPILAÇÃO! %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                % STARTUP
                fcn.winPosition(app)
                jsBackDoor_Initialization(app)
                TimerCreation(app)

            catch ME
                layoutFcn.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            % DELETE TIMER
            h = timerfindall;
            stop(h); delete(h); clear h

            delete(app)
            
        end

        % Image clicked function: menu_Button1Icon, menu_Button2Icon, 
        % ...and 2 other components
        function TabSelectionChanged(app, event)
            
            idx = str2double(event.Source.Tag);
            NN  = numel(app.menu_ControlGrid.ColumnWidth);

            for ii = 1:NN
                switch ii
                    case idx
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = "1x";', ii))
                    otherwise
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = 0;',    ii))
                end
            end

            columnWidth      = repmat({22}, 1, NN);
            columnWidth(idx) = {'1x'};

            app.menu_ControlGrid.ColumnWidth = columnWidth;
            app.menu_Underline.Layout.Column = idx;
            app.menu_TabGroup.SelectedTab    = app.menu_TabGroup.Children(idx);

            focus(app.jsBackDoor)

        end

        % Value changing function: search_entryPoint
        function search_entryPointValueChanging(app, event)

            filterEntryChanging(app, event.Value, true)

        end

        % Image clicked function: search_entryPointImage
        function search_entryPointImageClicked(app, event)
            
            value2Search = fcn.PreProcessedData(app.search_entryPoint.Value);
            words2Search = app.search_Suggestions.Items;
            
            % FILTRAGEM
            if ~isempty(words2Search)
                filter_main(app, words2Search)
                layout_checkTableVisibility(app)
                
                nWords2Search  = numel(words2Search);
                nWordsContains = sum(contains(words2Search, value2Search));
                listOfWords    = words2Search(1:min([20, nWords2Search]));
                listOfColumns  = {'Modelo', 'Nome Comercial'};                       % PENDENTE INSERIR LISTA DE COLUNAS!

                if nWordsContains < nWords2Search
                    searchNote = ' e similares';
                else
                    searchNote     = '';
                end
    
                % TÍTULO DA BUSCA
                app.search_Metadata.Text = sprintf('Exibindo resultados para "<b>%s</b>"%s\n<p style="color: #808080; font-size:10px;">Palavras-chave: %s</p>', ...
                                                   app.search_entryPoint.Value, searchNote, jsonencode(struct('Qtd', sprintf('%d de %d', nWordsContains, nWords2Search), 'Amostra', {listOfWords}, 'Coluna', {listOfColumns})));
            end

            % % LEANDRO
            % value2Search  = app.search_entryPoint.Value;
            % words2Search  = app.search_Suggestions.Items;
            % 
            % FilterType = filter_Columns_Type(app);
            % 
            % % TÍTULO DA BUSCA
            % 
            % app.search_Metadata.Text = sprintf(['Exibindo resultados para "<b>%s</b>"\n' ...
            %                                     '<p style="color: #808080; font-size:10px;">Palavras-chave: %s</p>' ...
            %                                     '<p style="color: #808080; font-size:10px;">Pesquisa realizada na(s) coluna(s) %s</p>'], value2Search, strjoin("""" + string(words2Search) + """", ', '),strjoin("""" + string(FilterType) + """", ', '));
            % 
            % % FILTRAGEM
            % filter_main(app, words2Search, FilterType);
            % layout_checkTableVisibility(app);

        end

        % Image clicked function: menu_btnReport, menu_btnSearch, 
        % ...and 1 other component
        function Image3Clicked(app, event)
            
            switch event.Source
                case app.menu_btnSearch
                    app.GridLayout2.ColumnWidth(4:5) = {'1x', 0};
                    app.menu_Title.Text = 'SCH > <font style="color:#bfbf13;"><b>Consulta geral</b></font>';
                
                case app.menu_btnReport
                    app.GridLayout2.ColumnWidth(4:5) = {0, '1x'};
                    app.menu_Title.Text = 'SCH > <font style="color:#bfbf13;"><b>Relatório</b></font>';

                case app.menu_btnSettings
                    if app.GridLayout2.ColumnWidth{2}
                        app.GridLayout2.ColumnWidth(1:2) = {0, 0};
                    else
                        app.GridLayout2.ColumnWidth(1:2) = {5, 300};
                    end
            end

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
            hitObject = struct(event).HitObject;
            switch hitObject
                case app.search_entryPoint
                    switch app.search_entryPoint.Value
                        case 'O que você quer pesquisar?'
                            set(app.search_entryPoint, 'FontColor', [0,0,0], 'Value', '')

                        otherwise
                            if numel(app.search_entryPoint.Value) > 1
                                app.search_Suggestions.Visible = 1;
                            end
                    end

                case app.search_Suggestions
                    if isempty(app.search_Suggestions.Value)
                        waitfor(app.search_Suggestions, 'Value')
                    end

                    app.changingSearch = false;
                    jsBackDoor_keyDownListener(app, struct('HTMLEventData', 'app.search_Suggestions', 'HTMLEventName', 'Enter'))

                otherwise
                    set(app.search_Suggestions, Visible=0, Value={})
                    if isempty(app.search_entryPoint.Value)
                        set(app.search_entryPoint, 'FontColor', [.8,.8,.8], 'Value', 'O que você quer pesquisar?')
                    end                    
            end

        end

        % Selection changed function: search_Table
        function search_TableSelectionChanged(app, event)
            
            selectedRow = app.search_Table.Selection;
            selectedHom = app.search_Table.Data.("Homologação"){selectedRow};

            idx = find(strcmp(app.rawDataTable.("Homologação"), selectedHom));
            app.notes_ProductInfo.HTMLSource = fcn.HTMLList(app.rawDataTable, idx, app.rootFolder);
            
        end

        % Value changed function: htmlListView
        function htmlListViewValueChanged(app, event)
            
            if app.htmlListView.Value
                filteredIndex = app.search_Table.UserData;            
                set(app.search_List, 'HTMLSource', fcn.HTMLList(app.rawDataTable, filteredIndex, app.rootFolder), ...
                                     'HTMLEventReceivedFcn', @(~,event)app.HTMLEventReceivedFcn(event))

                app.DocumentGrid.ColumnWidth(5:6) = {5, 340};
            else
                app.DocumentGrid.ColumnWidth(5:6) = {0, 0};
            end

        end

        % Image clicked function: menu_btnInfo
        function menu_btnInfoClicked(app, event)
            
            try
                [~, SCHLinks] = fcn.PublicLinks(app.rootFolder);
                web(SCHLinks.Sharepoint)
            catch ME
                uialert(app.UIFigure, ME.message, '', 'Icon', 'error')
            end

        end

        % Image clicked function: notes_addImage
        function notes_addImageClicked(app, event)
            
            selectedRow   = app.search_Table.Selection;
            selectedHom   = app.search_Table.Data.("Homologação")(selectedRow);

            selectedAtt   = app.notes_Type.Value;
            insertedValue = strjoin(strtrim(app.notes_Value.Value), ' ');

            newRowTable   = table({char(matlab.lang.internal.uuid())},   ...
                                  {datestr(now, 'dd/mm/yyyy HH:MM:SS')}, ...
                                  {getenv("COMPUTERNAME")},              ...
                                  {getenv("USERNAME")},                  ...
                                  selectedHom,                           ...
                                  {selectedAtt},                         ...
                                  {insertedValue});

            app.annotationTempTable = addRow(app.annotationTempTable, newRowTable);

        end

        % Value changed function: filter_CertificationCheckBox
        function filter_CertificationCheckBoxValueChanged(app, event)
            
                if app.filter_CertificationCheckBox.Value
                   app.filter_Certification.Enable = "on";
                   app.filter_Certification.Value  = "";

                else
                   app.filter_Certification.Value  = "";
                   app.filter_Certification.Enable = "off";

                   secundaryFilterTrigger(app)
                end

        end

        % Value changed function: filter_StatusCheckBox
        function filter_StatusCheckBoxValueChanged(app, event)
            
            if app.filter_StatusCheckBox.Value
                app.filter_Status.Enable = "on";

                primaryIndex = app.search_Table.UserData.PrimaryIndex;
                columnName   = app.filter_StatusCheckBox.Text;

                app.filter_Status.Items = [{''}; cellstr(unique(app.rawSCHTable{primaryIndex, columnName}))];

            else
                app.filter_Status.Enable = "off";
                app.filter_Status.Items  = {};

                secundaryFilterTrigger(app)
            end

        end

        % Callback function: filter_Applicant, filter_CNPJ, 
        % ...and 6 other components
        function secundaryFilterTrigger(app, event)
            
            primaryIndex = app.search_Table.UserData.PrimaryIndex;
            checkboxList = findobj(app.filter_SecundaryGrid, 'Type', 'uicheckbox', 'Value', 1);            

            if ~isempty(checkboxList)
                columnNames   = arrayfun(@(x) x.Text, checkboxList, 'UniformOutput', false);
                logicalMatrix = zeros(numel(primaryIndex), numel(columnNames), 'logical');
    
                for ii = 1:numel(columnNames)
                    columnName = columnNames{ii};

                    if exist('event', 'var') && strcmp(event.Source.Tag, columnName)
                        value2Search = fcn.PreProcessedData(event.Value);
                    else
                        editfieldObj = findobj(app.filter_SecundaryGrid, 'Tag', columnName);
                        value2Search = fcn.PreProcessedData(editfieldObj.Value);                        
                    end
    
                    logicalMatrix(:,ii) = contains(app.rawSCHTable{primaryIndex, sprintf('_%s', columnName)}, value2Search);                
                end
                logicalArray = all(logicalMatrix, 2);
                    
                app.search_Table.Data = app.rawSCHTable(primaryIndex(logicalArray), 1:11);         

            else
                app.search_Table.Data = app.rawSCHTable(primaryIndex, 1:11);
            end
            drawnow

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'Icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.menu_Grid = uigridlayout(app.GridLayout2);
            app.menu_btnSettings = uiimage(app.menu_Grid);
            app.menu_btnSettings.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.menu_btnSettings.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Settings_36x36W.png');
            app.menu_Title = uilabel(app.menu_Grid);
            app.menu_Title.Interpreter = 'html';
            app.menu_Title.Text = 'SCH > <font style="color:#f0f022;"><b>Consulta geral</b></font>';
            app.menu_btnSearch = uiimage(app.menu_Grid);
            app.menu_btnSearch.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.menu_btnSearch.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Zoom_36x36W.png');
            app.menu_btnReport = uiimage(app.menu_Grid);
            app.menu_btnReport.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.menu_btnReport.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Report_36x36W.png');
            app.menu_btnInfo = uiimage(app.menu_Grid);
            app.menu_btnInfo.ImageClickedFcn = createCallbackFcn(app, @menu_btnInfoClicked, true);
            app.menu_btnInfo.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Info_36x36W.png');
            app.jsBackDoor = uihtml(app.menu_Grid);
            app.menu_TabGroupGrid = uigridlayout(app.GridLayout2);
            app.menu_TabGroup = uitabgroup(app.menu_TabGroupGrid);
            app.Tab1 = uitab(app.menu_TabGroup);
            app.Tab1Grid = uigridlayout(app.Tab1);
            app.filter_PrimaryPanelLabel = uilabel(app.Tab1Grid);
            app.filter_PrimaryPanelLabel.Text = 'PRIMÁRIA';
            app.filter_PrimaryPanel = uibuttongroup(app.Tab1Grid);
            app.filter_PrimaryOption1 = uiradiobutton(app.filter_PrimaryPanel);
            app.filter_PrimaryOption1.Text = 'Homologação';
            app.filter_PrimaryOption1.Value = true;
            app.filter_PrimaryOption2 = uiradiobutton(app.filter_PrimaryPanel);
            app.filter_PrimaryOption2.Text = 'Modelo e Nome Comercial';
            app.filter_PrimaryOption3 = uicheckbox(app.filter_PrimaryPanel);
            app.filter_PrimaryOption3.Enable = 'off';
            app.filter_PrimaryOption3.Text = 'Anotações';
            app.filter_PrimaryOption2_2 = uiradiobutton(app.filter_PrimaryPanel);
            app.filter_PrimaryOption2_2.Text = 'Solicitante e Fabricante';
            app.filter_PrimaryOption2_3 = uiradiobutton(app.filter_PrimaryPanel);
            app.filter_PrimaryOption2_3.Text = '"Solicitante" e "Fabricante"';
            app.filter_PrimaryOption2_4 = uiradiobutton(app.filter_PrimaryPanel);
            app.filter_PrimaryOption2_4.Text = '"Solicitante" e "Fabricante"';
            app.filter_SecundaryPanelLabel = uilabel(app.Tab1Grid);
            app.filter_SecundaryPanelLabel.Text = 'COMPLEMENTAR';
            app.filter_SecundaryPanel = uipanel(app.Tab1Grid);
            app.filter_SecundaryGrid = uigridlayout(app.filter_SecundaryPanel);
            app.filter_CertificationCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_CertificationCheckBox.ValueChangedFcn = createCallbackFcn(app, @filter_CertificationCheckBoxValueChanged, true);
            app.filter_CertificationCheckBox.Enable = 'off';
            app.filter_CertificationCheckBox.Text = 'Homologação';
            app.filter_Certification = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_Certification.CharacterLimits = [12 12];
            app.filter_Certification.InputType = 'digits';
            app.filter_Certification.ValueChangingFcn = createCallbackFcn(app, @secundaryFilterTrigger, true);
            app.filter_Certification.Tag = 'Homologação';
            app.filter_Certification.Enable = 'off';
            app.filter_ProductListCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_ProductListCheckBox.Enable = 'off';
            app.filter_ProductListCheckBox.Text = 'Tipo';
            app.filter_ProductList = uidropdown(app.filter_SecundaryGrid);
            app.filter_ProductList.Items = {''};
            app.filter_ProductList.ValueChangedFcn = createCallbackFcn(app, @secundaryFilterTrigger, true);
            app.filter_ProductList.Tag = 'Tipo';
            app.filter_ProductList.Enable = 'off';
            app.filter_ProductList.Value = '';
            app.filter_ApplicantCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_ApplicantCheckBox.Enable = 'off';
            app.filter_ApplicantCheckBox.Text = 'Solicitante';
            app.filter_Applicant = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_Applicant.ValueChangingFcn = createCallbackFcn(app, @secundaryFilterTrigger, true);
            app.filter_Applicant.Tag = 'Solicitante';
            app.filter_Applicant.Enable = 'off';
            app.filter_CNPJCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_CNPJCheckBox.Enable = 'off';
            app.filter_CNPJCheckBox.Text = 'CNPJ';
            app.filter_CNPJ = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_CNPJ.ValueChangingFcn = createCallbackFcn(app, @secundaryFilterTrigger, true);
            app.filter_CNPJ.Tag = 'CNPJ';
            app.filter_CNPJ.Enable = 'off';
            app.filter_ManufacturerCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_ManufacturerCheckBox.Enable = 'off';
            app.filter_ManufacturerCheckBox.Text = 'Fabricante';
            app.filter_Manufacturer = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_Manufacturer.ValueChangingFcn = createCallbackFcn(app, @secundaryFilterTrigger, true);
            app.filter_Manufacturer.Tag = 'Fabricante';
            app.filter_Manufacturer.Enable = 'off';
            app.filter_ModelCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_ModelCheckBox.Enable = 'off';
            app.filter_ModelCheckBox.Text = 'Modelo';
            app.filter_Model = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_Model.ValueChangingFcn = createCallbackFcn(app, @secundaryFilterTrigger, true);
            app.filter_Model.Tag = 'Modelo';
            app.filter_Model.Enable = 'off';
            app.filter_TradeNameCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_TradeNameCheckBox.Enable = 'off';
            app.filter_TradeNameCheckBox.Text = 'Nome Comercial';
            app.filter_TradeName = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_TradeName.ValueChangingFcn = createCallbackFcn(app, @secundaryFilterTrigger, true);
            app.filter_TradeName.Tag = 'Nome Comercial';
            app.filter_TradeName.Enable = 'off';
            app.filter_StatusCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_StatusCheckBox.ValueChangedFcn = createCallbackFcn(app, @filter_StatusCheckBoxValueChanged, true);
            app.filter_StatusCheckBox.Enable = 'off';
            app.filter_StatusCheckBox.Text = 'Situação';
            app.filter_Status = uidropdown(app.filter_SecundaryGrid);
            app.filter_Status.Items = {''};
            app.filter_Status.ValueChangedFcn = createCallbackFcn(app, @secundaryFilterTrigger, true);
            app.filter_Status.Tag = 'Situação';
            app.filter_Status.Enable = 'off';
            app.filter_Status.Value = '';
            app.Tab2 = uitab(app.menu_TabGroup);
            app.Tab2Grid = uigridlayout(app.Tab2);
            app.notes_Label1 = uilabel(app.Tab2Grid);
            app.notes_Label1.Text = 'PRODUTO SELECIONADO';
            app.notes_Panel1 = uipanel(app.Tab2Grid);
            app.notes_Panel1Grid = uigridlayout(app.notes_Panel1);
            app.notes_ProductInfo = uihtml(app.notes_Panel1Grid);
            app.notes_Label2 = uilabel(app.Tab2Grid);
            app.notes_Label2.Text = 'ANOTAÇÃO';
            app.notes_Panel2 = uipanel(app.Tab2Grid);
            app.notes_Panel2Grid = uigridlayout(app.notes_Panel2);
            app.notes_TypeLabel = uilabel(app.notes_Panel2Grid);
            app.notes_TypeLabel.Text = 'Tipo:';
            app.notes_Type = uidropdown(app.notes_Panel2Grid);
            app.notes_Type.Items = {'Fornecedor', 'Fabricante', 'Modelo', 'Outras informações', 'Termos relacionados (Google)'};
            app.notes_Type.Value = 'Fornecedor';
            app.notes_ValueLabel = uilabel(app.notes_Panel2Grid);
            app.notes_ValueLabel.Text = 'Valor:';
            app.notes_Value = uitextarea(app.notes_Panel2Grid);
            app.notes_addImage = uiimage(app.Tab2Grid);
            app.notes_addImage.ImageClickedFcn = createCallbackFcn(app, @notes_addImageClicked, true);
            app.notes_addImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Add_18x18.png');
            app.notes_Tree = uitree(app.Tab2Grid);
            app.Tab3 = uitab(app.menu_TabGroup);
            app.Tab3Grid = uigridlayout(app.Tab3);
            app.report_TreeLabel = uilabel(app.Tab3Grid);
            app.report_TreeLabel.Text = 'PRODUTO SELECIONADO';
            app.report_Tree = uitree(app.Tab3Grid);
            app.Node = uitreenode(app.report_Tree);
            app.Node.Text = '#1: 04546-08-09892';
            app.APPLEIPHONE22Node = uitreenode(app.report_Tree);
            app.APPLEIPHONE22Node.Text = '#2: APPLE IPHONE 22';
            app.Node_3 = uitreenode(app.report_Tree);
            app.Node_3.Text = '#3: 04546-08-09893';
            app.report_SelectedProductLabel = uilabel(app.Tab3Grid);
            app.report_SelectedProductLabel.Text = 'EVIDÊNCIAS';
            app.report_SelectedProductPanel = uipanel(app.Tab3Grid);
            app.report_SelectedProductGrid = uigridlayout(app.report_SelectedProductPanel);
            app.report_QuantityLabel = uilabel(app.report_SelectedProductGrid);
            app.report_QuantityLabel.Text = 'Quantidade:';
            app.report_Quantity = uieditfield(app.report_SelectedProductGrid, 'numeric');
            app.report_MonetaryValueLabel = uilabel(app.report_SelectedProductGrid);
            app.report_MonetaryValueLabel.Text = 'Valor unitário (R$):';
            app.report_MonetaryValue = uieditfield(app.report_SelectedProductGrid, 'numeric');
            app.report_ComplementaryNotesLabel = uilabel(app.report_SelectedProductGrid);
            app.report_ComplementaryNotesLabel.Text = 'Informações complementares:';
            app.report_ComplementaryNotes = uitextarea(app.report_SelectedProductGrid);
            app.TipoDropDownLabel = uilabel(app.report_SelectedProductGrid);
            app.TipoDropDownLabel.Text = 'Tipo:';
            app.TipoDropDown = uidropdown(app.report_SelectedProductGrid);
            app.TipoDropDown.Items = {''};
            app.TipoDropDown.Value = '';
            app.notes_addImage_2 = uiimage(app.Tab3Grid);
            app.notes_addImage_2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Add_18x18.png');
            app.Tab4 = uitab(app.menu_TabGroup);
            app.misc_ElevationSourceGrid = uigridlayout(app.Tab4);
            app.ASPECTOSGERAISLabel = uilabel(app.misc_ElevationSourceGrid);
            app.ASPECTOSGERAISLabel.Text = 'ASPECTOS GERAIS';
            app.basemapPanel = uipanel(app.misc_ElevationSourceGrid);
            app.basemapGrid = uigridlayout(app.basemapPanel);
            app.htmlListView = uicheckbox(app.basemapGrid);
            app.htmlListView.ValueChangedFcn = createCallbackFcn(app, @htmlListViewValueChanged, true);
            app.htmlListView.Text = 'Visualização dos produtos em lista.';
            app.PastadetrabalhoLabel = uilabel(app.basemapGrid);
            app.PastadetrabalhoLabel.Text = 'Pasta de trabalho:';
            app.GridLayout9 = uigridlayout(app.basemapGrid);
            app.DropDown = uidropdown(app.GridLayout9);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            app.Button = uibutton(app.GridLayout9, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.Button.Text = '';
            app.AlgoritmodesugestodepalavraschaveLabel = uilabel(app.basemapGrid);
            app.AlgoritmodesugestodepalavraschaveLabel.Text = 'Algoritmo de sugestão de palavras-chave:';
            app.Panel = uipanel(app.basemapGrid);
            app.GridLayout10 = uigridlayout(app.Panel);
            app.selectedBasemapLabel = uilabel(app.GridLayout10);
            app.selectedBasemapLabel.Text = {'Qtd. mínima '; 'palavras-chave:'};
            app.selectedBasemap = uidropdown(app.GridLayout10);
            app.selectedBasemap.Items = {'10', '20', '40'};
            app.selectedBasemap.Value = '10';
            app.QtdmnimacaracteresSpinnerLabel = uilabel(app.GridLayout10);
            app.QtdmnimacaracteresSpinnerLabel.Text = 'Qtd. mínima caracteres:';
            app.QtdmnimacaracteresSpinner = uispinner(app.GridLayout10);
            app.QtdmnimacaracteresSpinner.Limits = [2 4];
            app.QtdmnimacaracteresSpinner.RoundFractionalValues = 'on';
            app.QtdmnimacaracteresSpinner.ValueDisplayFormat = '%d';
            app.QtdmnimacaracteresSpinner.Value = 2;
            app.menu_ControlGrid = uigridlayout(app.menu_TabGroupGrid);
            app.menu_Underline = uiimage(app.menu_ControlGrid);
            app.menu_Underline.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineH.png');
            app.menu_Button1Grid = uigridlayout(app.menu_ControlGrid);
            app.menu_Button1Label = uilabel(app.menu_Button1Grid);
            app.menu_Button1Label.Text = 'Filtragem';
            app.menu_Button1Icon = uiimage(app.menu_Button1Grid);
            app.menu_Button1Icon.ImageClickedFcn = createCallbackFcn(app, @TabSelectionChanged, true);
            app.menu_Button1Icon.Tag = '1';
            app.menu_Button1Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Filter_18x18.png');
            app.menu_Button2Grid = uigridlayout(app.menu_ControlGrid);
            app.menu_Button2Label = uilabel(app.menu_Button2Grid);
            app.menu_Button2Label.Text = 'Anotação na base de dados';
            app.menu_Button2Icon = uiimage(app.menu_Button2Grid);
            app.menu_Button2Icon.ImageClickedFcn = createCallbackFcn(app, @TabSelectionChanged, true);
            app.menu_Button2Icon.Tag = '2';
            app.menu_Button2Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Edit_18x18.png');
            app.menu_Button3Grid = uigridlayout(app.menu_ControlGrid);
            app.menu_Button3Label = uilabel(app.menu_Button3Grid);
            app.menu_Button3Label.Text = 'Evidências da comercialização';
            app.menu_Button3Icon = uiimage(app.menu_Button3Grid);
            app.menu_Button3Icon.ImageClickedFcn = createCallbackFcn(app, @TabSelectionChanged, true);
            app.menu_Button3Icon.Tag = '3';
            app.menu_Button3Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'List_18x18.png');
            app.menu_Button4Grid = uigridlayout(app.menu_ControlGrid);
            app.menu_Button4Label = uilabel(app.menu_Button4Grid);
            app.menu_Button4Label.Text = 'Configurações';
            app.menu_Button4Icon = uiimage(app.menu_Button4Grid);
            app.menu_Button4Icon.ImageClickedFcn = createCallbackFcn(app, @TabSelectionChanged, true);
            app.menu_Button4Icon.Tag = '4';
            app.menu_Button4Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Dots_18x18.png');
            app.DocumentGrid = uigridlayout(app.GridLayout2);
            app.search_entryPointPanel = uipanel(app.DocumentGrid);
            app.search_entryPointGrid = uigridlayout(app.search_entryPointPanel);
            app.search_entryPointImage = uiimage(app.search_entryPointGrid);
            app.search_entryPointImage.ImageClickedFcn = createCallbackFcn(app, @search_entryPointImageClicked, true);
            app.search_entryPointImage.Enable = 'off';
            app.search_entryPointImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Zoom_36x36.png');
            app.search_entryPoint = uieditfield(app.search_entryPointGrid, 'text');
            app.search_entryPoint.CharacterLimits = [0 128];
            app.search_entryPoint.ValueChangingFcn = createCallbackFcn(app, @search_entryPointValueChanging, true);
            app.search_entryPoint.Tag = 'PROMPT';
            app.search_entryPoint.Value = 'O que você quer pesquisar?';
            app.search_Metadata = uilabel(app.DocumentGrid);
            app.search_Metadata.Visible = 'off';
            app.search_Metadata.Interpreter = 'html';
            app.search_Metadata.Text = {'Exibindo resultados para "<b>apple iphone</b>"'; '<p style="color: #808080; font-size:10px;">Pesquisa realizada nas colunas "Nome do Produto" e "Modelo Comercial".</p><p style="color: #808080; font-size:10px;">Pesquisa realizada nas colunas "Nome do Produto" e "Modelo Comercial".</p>'};
            app.search_Table = uitable(app.DocumentGrid);
            app.search_Table.ColumnName = {'HOMOLOGAÇÃO'; 'TIPO'; 'SOLICITANTE'; 'FABRICANTE'; 'MODELO'; 'NOME COMERCIAL'; 'SITUAÇÃO'};
            app.search_Table.RowName = {};
            app.search_Table.ColumnSortable = true;
            app.search_Table.SelectionType = 'row';
            app.search_Table.SelectionChangedFcn = createCallbackFcn(app, @search_TableSelectionChanged, true);
            app.search_Table.Multiselect = 'off';
            app.search_Table.Visible = 'off';
            app.search_Suggestions = uilistbox(app.DocumentGrid);
            app.search_Suggestions.Items = {};
            app.search_Suggestions.Tag = 'CAIXA DE BUSCA';
            app.search_Suggestions.Visible = 'off';
            app.search_Suggestions.Value = {};
            app.tool_nRows = uilabel(app.DocumentGrid);
            app.tool_nRows.Visible = 'off';
            app.tool_nRows.Text = '# 0';
            app.search_List = uihtml(app.DocumentGrid);
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.MODORELATRIOLabel = uilabel(app.GridLayout8);
            app.MODORELATRIOLabel.Text = {'MODO'; 'RELATÓRIO'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSCH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end